classdef EStop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Switch2Label  matlab.ui.control.Label
        Switch2       matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
value = app.Switch2.Value;
      flag = 1;
% set(app.Switch2, 'Text', 'PAUSE');

% while (flag)
% 
%     drawnow
%  test = 'test1'   
%   f = get(app.Switch2,'Value');   
    
%  if isequal(f, 0)
%       test = 'test4'  
% %         set(app.Switch2, 'Text', 'RESUME');
%         flag = 0; 
%         break;
%       elseif ~isequal(f,1)
% %         set(app.Switch2, 'Text', 'Pause');
%  end
   test = 'test2'  
            cheeseRobot = czRobot;
              test = 'test5' 
warning('off')
%% UR3 Get to the 1st bread
% use q = ans.UR3.getpos() to find q
ori_q = cheeseRobot.UR3.getpos();
ori_q = [0 0 0 0 0 0];
% set steps = 50 ez for demonstration
steps = 50;
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([0.185,-0.53,0.99]) * troty(pi) * trotz(pi));
% create jtraj
bot_bread = jtraj(ori_q, targ_q, steps);
% start animation

for i = 1:steps
    while ~(flag)
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(2*pi/3));
    cheeseRobot.gp_fg2.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(-2*pi/3));
    cheeseRobot.gp_fg3.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(bot_bread(i,:));
    drawnow();
  
end
 test = 'test3'  

% Gripper Grab -> Gripper finger 1
%% UR3 Place the 1st bread
% Place 1st Bread motion Have to pick the bread up alit bit more
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% set joint_q -> its just pull the bread up
targ_q = ori_q; targ_q(2:4) = [-0.0202 -0.0703 1.7]; % -> only joint2 3 4 moves => Just "pull" the bread up
% create jtraj
bot_bread = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    while ~(flag)
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(295,55,158,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(290,300,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    %bread1
    br_cur = G_T_cur; br_cur(3,end) = br_cur(3,end) - 0.12; % 1-0.12=0.88
    rotateTRy = makehgtform('yrotate',(pi/2));
    rotateTRx = makehgtform('xrotate',(pi));
    cheeseRobot.bread1.basePose = br_cur * rotateTRy * rotateTRx;
    updatedPoints = [cheeseRobot.bread1.basePose * [cheeseRobot.bread1.baseVerts,ones(cheeseRobot.bread1.VertexCount,1)]']'; % get the new position
    cheeseRobot.bread1s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    % robot's action
    cheeseRobot.UR3.animate(bot_bread(i,:));
    drawnow();
end

% NOW place the bread  - Gripper close!!!
ori_q = cheeseRobot.UR3.getpos(); % use q = ans.UR3.getpos() to find current q
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([-0.15,-0.1,1]) * troty(-pi));
% create jtraj
bot_bread = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    while ~(flag)
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(295,55,158,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(290,300,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    %bread1
    br_cur = G_T_cur; br_cur(3,end) = br_cur(3,end) - 0.12; % 1-0.12=0.88
    rotateTRy = makehgtform('yrotate',(pi/2));
    rotateTRx = makehgtform('xrotate',(pi));
    cheeseRobot.bread1.basePose = br_cur * rotateTRy * rotateTRx;
    updatedPoints = [cheeseRobot.bread1.basePose * [cheeseRobot.bread1.baseVerts,ones(cheeseRobot.bread1.VertexCount,1)]']'; % get the new position
    cheeseRobot.bread1s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    % robot's action
    cheeseRobot.UR3.animate(bot_bread(i,:));
    drawnow();
end

%% Get to the cheese block
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([0.275,-0.685,0.95]) * troty(pi));
% create jtraj
cheese_block = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    while ~(flag)
    end
    % bread drop
    if i < 10
        br_cur(3,end) = br_cur(3,end) - 0.008; % 0.88-0.08=0.8
        rotateTRx = makehgtform('xrotate',(pi));
        rotateTRy = makehgtform('yrotate',(pi));
        if i == 10, cheeseRobot.bread1.basePose = br_cur * rotateTRx * rotateTRy;
        else cheeseRobot.bread1.basePose = br_cur;end
        updatedPoints = [cheeseRobot.bread1.basePose * [cheeseRobot.bread1.baseVerts,ones(cheeseRobot.bread1.VertexCount,1)]']'; % get the new position
        cheeseRobot.bread1s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1 OVEROPEN
    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(110,0,0,'deg');
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(240,45,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(50,240,325,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    
    % robot's action
    cheeseRobot.UR3.animate(cheese_block(i,:));
    drawnow();
end
%% Place the cheese block & cheese model change
% Place the cheese block motion Have to pick the the cheese block up alit bit more
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% set joint_q -> its just pull the cheese up
targ_q = ori_q; targ_q(2) = -0.4803; targ_q(4) = 1.4034; % -> only joint2 4 moves => Just "pull" the cheese up
% create jtraj
cheese_block = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    while (flag)
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1  bit_OPEN
    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(100,0,0,'deg');
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(260,45,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(40,260,310,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % cheese block
    cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
    cheeseRobot.c_block.basePose = cb_cur;
    updatedPoints = [cheeseRobot.c_block.basePose * [cheeseRobot.c_block.baseVerts,ones(cheeseRobot.c_block.VertexCount,1)]']'; % get the new position
    cheeseRobot.c_block1.Vertices = updatedPoints(:,1:3); % updated the c_block's location
    % robot's action
    cheeseRobot.UR3.animate(cheese_block(i,:));
    drawnow();
end

% NOW place the cheese block - Gripper close!!! -> but for cheese block
% should be over-open! because its 'huge'
ori_q = cheeseRobot.UR3.getpos(); % use q = ans.UR3.getpos() to find current q
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([-0.153,0.0422,0.9076]) * trotx(-pi));
% create jtraj
cheese_block = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    while (flag)
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1  bit_OPEN
    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(100,0,0,'deg');
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(260,45,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(40,260,310,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % c_block
    cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
    cheeseRobot.c_block.basePose = cb_cur;
    updatedPoints = [cheeseRobot.c_block.basePose * [cheeseRobot.c_block.baseVerts,ones(cheeseRobot.c_block.VertexCount,1)]']'; % get the new position
    cheeseRobot.c_block1.Vertices = updatedPoints(:,1:3); % updated the c_block's location
    % replace the cheese model
    if i == 50
        % remove c_block
        cb_cur = G_T_cur; cb_cur(1,end) = 0; cb_cur(2,end) = 0;cb_cur(3,end) = 0.5; % pick the c_block a little bit more 0.97-0.08=0.89
        cheeseRobot.c_block.basePose = cb_cur;
        updatedPoints = [cheeseRobot.c_block.basePose * [cheeseRobot.c_block.baseVerts,ones(cheeseRobot.c_block.VertexCount,1)]']'; % get the new position
        cheeseRobot.c_block1.Vertices = updatedPoints(:,1:3); % updated the c_block's location
        % replace with c_block2 and c_slice
        cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
        cheeseRobot.c_blocks.basePose = cb_cur;
        updatedPoints = [cheeseRobot.c_blocks.basePose * [cheeseRobot.c_blocks.baseVerts,ones(cheeseRobot.c_blocks.VertexCount,1)]']'; % get the new position
        cheeseRobot.c_block2.Vertices = updatedPoints(:,1:3); % updated the c_block's location
        % c_slice
        cb_cur = G_T_cur; cb_cur(1,end) = cb_cur(1,end) + 0.08; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
        cheeseRobot.c_slice.basePose = cb_cur;
        updatedPoints = [cheeseRobot.c_slice.basePose * [cheeseRobot.c_slice.baseVerts,ones(cheeseRobot.c_slice.VertexCount,1)]']'; % get the new position
        cheeseRobot.c_slice1.Vertices = updatedPoints(:,1:3); % updated the c_block's location
    end
    % robot's action
    cheeseRobot.UR3.animate(cheese_block(i,:));
    drawnow();
end

%% IRB Cut the cheese block
% Firstly, have to move the knife onto the cheese & collision detection then use q = ans.UR3/IRB.getpos() to find current q
ori_q_IRB = cheeseRobot.IRB.getpos();
ori_q_UR3 = cheeseRobot.UR3.getpos();
% get joint_q by using ikine
targ_q_IRB = cheeseRobot.IRB.ikine(transl([-0.0857,0.0484,0.9635]) * trotz(pi/2));
targ_q_UR3 = ori_q_UR3; targ_q_UR3(1) = 1.8552; % -> only joint1 moves 106degs => deg2rad(106)
% create jtraj
cut_cheese = jtraj(ori_q_IRB, targ_q_IRB, steps);
avoid_colli = jtraj(ori_q_UR3, targ_q_UR3, steps); % avoid collision -> UR3 & IRB
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(110,0,0,'deg');
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(240,45,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(50,240,325,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(avoid_colli(i,:));
    cheeseRobot.IRB.animate(cut_cheese(i,:));
    drawnow();
end

% NOW can cut the cheese block 0.0011269/0.159994=0.7%
% use q = ans.IRB.getpos() to find current q
ori_q_IRB = cheeseRobot.IRB.getpos();
% get joint_q by using ikine
targ_q_IRB = cheeseRobot.IRB.ikine(transl([-0.0829,0.035,0.9232]) * trotz(pi/2));
% create jtraj
cut_cheese = jtraj(ori_q_IRB, targ_q_IRB, steps);
% start animation
for i = 1:steps
    % robot's action
    cheeseRobot.IRB.animate(cut_cheese(i,:));
    drawnow();
    if i == 50
        % cut the cheese completely
        ori_q_IRB = cheeseRobot.IRB.getpos();
        % get joint_q by using ikine
        targ_q_IRB = ori_q_IRB; targ_q_IRB(5) = 0.8929; % -> only joint5 moves 51degs => deg2rad(51)
        % create jtraj
        cut_cheese = jtraj(ori_q_IRB, targ_q_IRB, steps);
        % start animation
        for j = 1:steps
            % robot's action
            cheeseRobot.IRB.animate(cut_cheese(j,:));
            drawnow();
            if j == 50
                % c_slice
                rotateTRy = makehgtform('yrotate',(pi/2));
                cheeseRobot.c_slice.basePose(1,end) = -0.045;cheeseRobot.c_slice.basePose(3,end) = 0.8;
                cheeseRobot.c_slice.basePose = cheeseRobot.c_slice.basePose * rotateTRy;
                updatedPoints = [cheeseRobot.c_slice.basePose * [cheeseRobot.c_slice.baseVerts,ones(cheeseRobot.c_slice.VertexCount,1)]']'; % get the new position
                cheeseRobot.c_slice1.Vertices = updatedPoints(:,1:3); % updated the c_slice's location
            end
        end
    end
end
%% IRB reset & UR3 put cheese back
% use q = ans.IRB/UR3.getpos() to find current q
ori_q_IRB = cheeseRobot.IRB.getpos();
ori_q_UR3 = cheeseRobot.UR3.getpos();

% get joint_q by using ikine
targ_q_IRB = cheeseRobot.IRB.ikine(transl([0.115,0.5,1.184]) * trotz(pi/2));
targ_q_UR3 = ori_q_UR3; targ_q_UR3(1) = 1.7295; % -> only joint1 moves 106degs => deg2rad(106)

% create jtraj
UR3_cz_back = jtraj(ori_q_UR3, targ_q_UR3, steps);  % UR3 put cheese back
IRB_reset = jtraj(ori_q_IRB, targ_q_IRB, steps); % IRB reset
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Grab !!! Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(110,0,0,'deg');
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(240,45,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(50,240,325,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(UR3_cz_back(i,:));
    cheeseRobot.IRB.animate(IRB_reset(i,:));
    drawnow();
    % pick the cheese up
    if i == 50
        ori_q_UR3 = cheeseRobot.UR3.getpos();
        targ_q_UR3 = ori_q_UR3; targ_q_UR3(2) = -1.3439; targ_q_UR3(4) = 0.9957;% -> only joint2&4 moves
        UR3_cz_back = jtraj(ori_q_UR3, targ_q_UR3, steps);
        for j = 1:steps
            G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
            % Grab !!! Gripper base
            cheeseRobot.gp_base.basePose = G_T_cur;
            updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
            cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
            % Gripper Grab -> Gripper finger 1  bit_OPEN
            cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(100,0,0,'deg');
            updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
            cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
            % Gripper finger 2
            cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(260,45,200,'deg');
            updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
            cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
            % Gripper finger 3
            cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(40,260,310,'deg');
            updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
            cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
            % c_block2
            cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
            cheeseRobot.c_blocks.basePose = cb_cur;
            updatedPoints = [cheeseRobot.c_blocks.basePose * [cheeseRobot.c_blocks.baseVerts,ones(cheeseRobot.c_blocks.VertexCount,1)]']'; % get the new position
            cheeseRobot.c_block2.Vertices = updatedPoints(:,1:3); % updated the c_block's location
            % robot's action
            cheeseRobot.UR3.animate(UR3_cz_back(j,:));
            drawnow();
            if j == 50 % put cheese back
                ori_q_UR3 = cheeseRobot.UR3.getpos();
                targ_q_UR3 = cheeseRobot.UR3.ikine(transl([0.2059,-0.6742,1.0865]) * troty(pi)  * trotx(pi/180));
                UR3_cz_back = jtraj(ori_q_UR3, targ_q_UR3, steps);
                for m = 1:steps
                    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
                    % Grab !!! Gripper base
                    cheeseRobot.gp_base.basePose = G_T_cur;
                    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
                    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
                    % Gripper Grab -> Gripper finger 1  bit_OPEN
                    cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(100,0,0,'deg');
                    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
                    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
                    % Gripper finger 2
                    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(260,45,200,'deg');
                    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
                    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
                    % Gripper finger 3
                    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(40,260,310,'deg');
                    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
                    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
                    % c_block2
                    cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
                    cheeseRobot.c_blocks.basePose = cb_cur;
                    updatedPoints = [cheeseRobot.c_blocks.basePose * [cheeseRobot.c_blocks.baseVerts,ones(cheeseRobot.c_blocks.VertexCount,1)]']'; % get the new position
                    cheeseRobot.c_block2.Vertices = updatedPoints(:,1:3); % updated the c_block's location
                    % robot's action
                    cheeseRobot.UR3.animate(UR3_cz_back(m,:));
                    drawnow();
                    if m == 50 % put cheese back
                        ori_q_UR3 = cheeseRobot.UR3.getpos();
                        targ_q_UR3 = ori_q_UR3; targ_q_UR3(2) = -1.7453; targ_q_UR3(4) = 0.7896; % -> only joint2 4 moves => Just "put" the cheese down
                        UR3_cz_back = jtraj(ori_q_UR3, targ_q_UR3, steps);
                        for n = 1:steps
                            G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
                            % Grab !!! Gripper base
                            cheeseRobot.gp_base.basePose = G_T_cur;
                            updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
                            cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
                            % Gripper Grab -> Gripper finger 1  bit_OPEN
                            cheeseRobot.gp_fg1.basePose = G_T_cur * rpy2tr(100,0,0,'deg');
                            updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
                            cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
                            % Gripper finger 2
                            cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(260,45,200,'deg');
                            updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
                            cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
                            % Gripper finger 3
                            cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(40,260,310,'deg');
                            updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
                            cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
                            % c_block2
                            cb_cur = G_T_cur; cb_cur(3,end) = cb_cur(3,end) - 0.08; % pick the c_block a little bit more 0.97-0.08=0.89
                            cheeseRobot.c_blocks.basePose = cb_cur;
                            updatedPoints = [cheeseRobot.c_blocks.basePose * [cheeseRobot.c_blocks.baseVerts,ones(cheeseRobot.c_blocks.VertexCount,1)]']'; % get the new position
                            cheeseRobot.c_block2.Vertices = updatedPoints(:,1:3); % updated the c_block's location
                            % robot's action
                            cheeseRobot.UR3.animate(UR3_cz_back(n,:));
                            drawnow();
                        end
                    end
                end
            end
        end
    end
end
%% UR3 get to the cheese slice
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% get joint_q by using ikine
targ_q = ori_q; targ_q(1) = 1.4661;targ_q(2) = -1.591;targ_q(3) = 1.6921;targ_q(4) = 1.5436;
% or targ_q = cheeseRobot.UR3.ikine(transl([-0.0095 0.0328 0.8581]) * rpy2tr(-180,0,0,'deg') );
% create jtraj
get_slice = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(2*pi/3));
    cheeseRobot.gp_fg2.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(-2*pi/3));
    cheeseRobot.gp_fg3.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(get_slice(i,:));
    drawnow();
end
%% UR3 place the cheese slice onto the 1st bread
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
%ori_q = [ 1.4661   -1.5910    1.6921    1.5436   -1.5880    1.4192];
% get joint_q by using ikine
%targ_q = cheeseRobot.UR3.ikine(transl([-0.1561 -0.0971 0.9193]) * rpy2tr(-0.3,-0.939,-68.32,'deg'));
% x=atan2(r32,r33);y=atan2(r21,r11);z=atan2(-r31,(r11/cos(y))); but its not working!!!
targ_q = [1.7977   -0.5516   -0.0646    2.1886   -1.5880    1.4192];
% create jtraj
get_slice = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(295,55,158,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(290,300,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % c_slice
    rotateTRy = makehgtform('yrotate',(-pi/2));
    cheeseRobot.c_slice.basePose = G_T_cur;cheeseRobot.c_slice.basePose(3,end) = cheeseRobot.c_slice.basePose(3,end) - 0.05;
    if i > 20
        cheeseRobot.c_slice.basePose(3,end) = cheeseRobot.c_slice.basePose(3,end) - 0.03;
        cheeseRobot.c_slice.basePose = cheeseRobot.c_slice.basePose;
    else
        cheeseRobot.c_slice.basePose(1,end) = cheeseRobot.c_slice.basePose(1,end) - 0.04;
        cheeseRobot.c_slice.basePose = cheeseRobot.c_slice.basePose * rotateTRy;
    end
    updatedPoints = [cheeseRobot.c_slice.basePose * [cheeseRobot.c_slice.baseVerts,ones(cheeseRobot.c_slice.VertexCount,1)]']'; % get the new position
    cheeseRobot.c_slice1.Vertices = updatedPoints(:,1:3); % updated the c_slice's location
    
    % c_slice drop
    if i == 50
        for j = 1:10
            cz_cur = G_T_cur;
            cz_cur(3,end) = cz_cur(3,end) - 0.1; % 0.88-0.08=0.8
            rotateTRy = makehgtform('yrotate',(pi/2));
            if j == 10, cheeseRobot.c_slice.basePose = cz_cur * rotateTRy;
            else cheeseRobot.c_slice.basePose = br_cur;end
            updatedPoints = [cheeseRobot.c_slice.basePose * [cheeseRobot.c_slice.baseVerts,ones(cheeseRobot.c_slice.VertexCount,1)]']'; % get the new position
            cheeseRobot.c_slice1.Vertices = updatedPoints(:,1:3); % updated the c_slice's location
        end
    end
    
    % robot's action
    cheeseRobot.UR3.animate(get_slice(i,:));
    drawnow();
end
%% UR3 get to the last bread
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([0.2075,-0.53,0.99]) * rpy2tr(0,180,180,'deg'));
% create jtraj
get_slice = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(2*pi/3));
    cheeseRobot.gp_fg2.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(-2*pi/3));
    cheeseRobot.gp_fg3.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(get_slice(i,:));
    drawnow();
end
%% UR3 place the last beard onto the cheese slice
% Place 1st Bread motion Have to pick the bread up alit bit more
% use q = ans.UR3.getpos() to find current q
ori_q = cheeseRobot.UR3.getpos();
% set joint_q -> its just pull the bread up
targ_q = ori_q; targ_q(2:4) = [0.1222 3.1416 1.4739]; % -> only joint2 3 4 moves => Just "pull" the bread up
% create jtraj
top_bread = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(295,55,158,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(290,300,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    %bread2
    br_cur = G_T_cur; br_cur(3,end) = br_cur(3,end) - 0.12; % 1-0.12=0.88
    rotateTRy = makehgtform('yrotate',(pi/2));
    rotateTRx = makehgtform('xrotate',(pi));
    cheeseRobot.bread2.basePose = br_cur * rotateTRy * rotateTRx;
    updatedPoints = [cheeseRobot.bread2.basePose * [cheeseRobot.bread2.baseVerts,ones(cheeseRobot.bread2.VertexCount,1)]']'; % get the new position
    cheeseRobot.bread2s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    % robot's action
    cheeseRobot.UR3.animate(top_bread(i,:));
    drawnow();
end

% NOW place the bread  - Gripper close!!!
ori_q = cheeseRobot.UR3.getpos(); % use q = ans.UR3.getpos() to find current q
% get joint_q by using ikine
targ_q = cheeseRobot.UR3.ikine(transl([-0.15,-0.1,1.05]) * trotx(pi));
% create jtraj
top_bread = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper Grab -> Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2.5));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    cheeseRobot.gp_fg2.basePose = G_T_cur * rpy2tr(295,55,158,'deg');
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    cheeseRobot.gp_fg3.basePose = G_T_cur * rpy2tr(290,300,200,'deg');
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    %bread1
    br_cur = G_T_cur; br_cur(3,end) = br_cur(3,end) - 0.12; % 1-0.12=0.88
    rotateTRy = makehgtform('yrotate',(pi/2));
    rotateTRx = makehgtform('xrotate',(pi));
    cheeseRobot.bread2.basePose = br_cur * rotateTRy * rotateTRx;
    updatedPoints = [cheeseRobot.bread2.basePose * [cheeseRobot.bread2.baseVerts,ones(cheeseRobot.bread2.VertexCount,1)]']'; % get the new position
    cheeseRobot.bread2s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    % robot's action
    cheeseRobot.UR3.animate(top_bread(i,:));
    drawnow();
end
%% UR3 reset
% reset UR3
ori_q = cheeseRobot.UR3.getpos(); % use q = ans.UR3.getpos() to find current q
% get joint_q by using ikine
targ_q = [0 0 0 0 0 0];
% create jtraj
top_bread = jtraj(ori_q, targ_q, steps);
% start animation
for i = 1:steps
    % bread drop
    if i < 10
        br_cur(3,end) = br_cur(3,end) - 0.011; % 0.88-0.08=0.8
        rotateTRx = makehgtform('xrotate',(pi));
        rotateTRy = makehgtform('yrotate',(pi));
        if i == 10, cheeseRobot.bread2.basePose = br_cur * rotateTRx * rotateTRy;
        else cheeseRobot.bread2.basePose = br_cur;end
        updatedPoints = [cheeseRobot.bread2.basePose * [cheeseRobot.bread2.baseVerts,ones(cheeseRobot.bread2.VertexCount,1)]']'; % get the new position
        cheeseRobot.bread2s.Vertices = updatedPoints(:,1:3); % updated the bread1's location
    end
    G_T_cur = cheeseRobot.UR3.fkine(cheeseRobot.UR3.getpos()); % find the ur3's current matrix
    % Gripper base
    cheeseRobot.gp_base.basePose = G_T_cur;
    updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
    % Gripper finger 1
    rotateTRx = makehgtform('xrotate',(pi/2));
    cheeseRobot.gp_fg1.basePose = G_T_cur * rotateTRx;
    updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % Gripper finger 2
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(2*pi/3));
    cheeseRobot.gp_fg2.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
    % Gripper finger 3
    rotateTRx = makehgtform('xrotate',(pi/2));
    rotateTRy = makehgtform('yrotate',(-2*pi/3));
    cheeseRobot.gp_fg3.basePose = G_T_cur * rotateTRx * rotateTRy;
    updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
    cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
    % robot's action
    cheeseRobot.UR3.animate(top_bread(i,:));
    drawnow();
end
%% Gripper_Release
% function gp_release(coor)
% % Gripper base
% cheeseRobot.gp_base.basePose = coor;
% updatedPoints = [cheeseRobot.gp_base.basePose * [cheeseRobot.gp_base.baseVerts,ones(cheeseRobot.gp_base.VertexCount,1)]']'; % get the new position
% cheeseRobot.gp_base1.Vertices = updatedPoints(:,1:3); % updated the gripper base's location
% % Gripper finger 1
% rotateTRx = makehgtform('xrotate',(pi/2));
% cheeseRobot.gp_fg1.basePose = coor * rotateTRx;
% updatedPoints = [cheeseRobot.gp_fg1.basePose * [cheeseRobot.gp_fg1.baseVerts,ones(cheeseRobot.gp_fg1.VertexCount,1)]']'; % get the new position
% cheeseRobot.gp_fg1s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location
% % Gripper finger 2
% rotateTRx = makehgtform('xrotate',(pi/2));
% rotateTRy = makehgtform('yrotate',(2*pi/3));
% cheeseRobot.gp_fg2.basePose = coor * rotateTRx * rotateTRy;
% updatedPoints = [cheeseRobot.gp_fg2.basePose * [cheeseRobot.gp_fg2.baseVerts,ones(cheeseRobot.gp_fg2.VertexCount,1)]']'; % get the new position
% cheeseRobot.gp_fg2s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 2's location
% % Gripper finger 3
% rotateTRx = makehgtform('xrotate',(pi/2));
% rotateTRy = makehgtform('yrotate',(-2*pi/3));
% cheeseRobot.gp_fg3.basePose = coor * rotateTRx * rotateTRy;
% updatedPoints = [cheeseRobot.gp_fg3.basePose * [cheeseRobot.gp_fg3.baseVerts,ones(cheeseRobot.gp_fg3.VertexCount,1)]']'; % get the new position
% cheeseRobot.gp_fg3s.Vertices = updatedPoints(:,1:3); % updated the gripper finger 1's location

% end

%         end      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EStop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end